classdef DialogNewMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_4                matlab.ui.container.Panel
        Panel_6                matlab.ui.container.Panel
        RelTolEditFieldLabel   matlab.ui.control.Label
        RelTolEditField        matlab.ui.control.NumericEditField
        AbsTolEditFieldLabel   matlab.ui.control.Label
        AbsTolEditField        matlab.ui.control.NumericEditField
        MaxStepEditFieldLabel  matlab.ui.control.Label
        MaxStepEditField       matlab.ui.control.NumericEditField
        EditField_7Label       matlab.ui.control.Label
        EditField_7            matlab.ui.control.NumericEditField
        EditField_8Label       matlab.ui.control.Label
        EditField_8            matlab.ui.control.NumericEditField
        DropDownLabel          matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        dPanel                 matlab.ui.container.Panel
        EditField_4Label       matlab.ui.control.Label
        EditField_4            matlab.ui.control.NumericEditField
        EditField_5Label       matlab.ui.control.Label
        EditField_5            matlab.ui.control.NumericEditField
        EditField_6Label       matlab.ui.control.Label
        EditField_6            matlab.ui.control.NumericEditField
        Panel_3                matlab.ui.container.Panel
        GammaEditField_3Label  matlab.ui.control.Label
        GammaEditField         matlab.ui.control.NumericEditField
        DeltaEditField_3Label  matlab.ui.control.Label
        DeltaEditField         matlab.ui.control.NumericEditField
        SigmaPanel             matlab.ui.container.Panel
        EditFieldLabel         matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        EditField_2Label       matlab.ui.control.Label
        EditField_2            matlab.ui.control.NumericEditField
        EditField_3Label       matlab.ui.control.Label
        EditField_3            matlab.ui.control.NumericEditField
        EditField_10           matlab.ui.control.NumericEditField
        EditField2             matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        EditField_9Label       matlab.ui.control.Label
        EditField_9            matlab.ui.control.EditField
        Button_2               matlab.ui.control.Button
    end

    
    properties (Access = protected)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Name = ['Map_Gamma_', num2str(app.GammaEditField.Value), '_Delta_', num2str(app.DeltaEditField.Value), '_Sigma_', num2str(app.EditField.Value), '_', num2str(app.EditField_2.Value), ...
                '_d_', num2str(app.EditField_4.Value), '_', num2str(app.EditField_5.Value), '_', num2str(app.EditField_3.Value), 'x',  num2str(app.EditField_6.Value)];
            if ~isempty(ls([Name, '*']))
                Name = [Name, '_(', num2str(size(ls([Name, '*']), 1)), ')'];
            end
            Name = [Name, '.mat'];
            app.EditField_9.Value = Name;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            SP = struct('Gamma', app.GammaEditField.Value, 'Delta', app.DeltaEditField.Value, 'Sigma', 0, 'd', 0);
            OP = struct('RelTol', app.RelTolEditField.Value, 'AbsTol', app.AbsTolEditField.Value, 'MaxStep', app.MaxStepEditField.Value);
            NP = struct('IC', [app.EditField_10.Value, app.EditField2.Value], 'Ttrans', app.EditField_7.Value, 'Tspan', app.EditField_8.Value, 'OP', OP, 'Mode', app.DropDown.Value);
            MP = struct('FirstParamBorder', [app.EditField.Value, app.EditField_2.Value], 'SecondParamBorder', [app.EditField_4.Value, app.EditField_5.Value], 'Width', app.EditField_3.Value, 'Height', app.EditField_6.Value);
            Map = struct('Name', app.EditField_9.Value, 'SP', SP, 'NP', NP, 'MP', MP, 'Progress', 0, 'ValueArray', nan(app.EditField_3.Value, app.EditField_6.Value), 'Duration', duration());
            save(Map.Name, 'Map');
            app.CallingApp.Map = Map;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Численные параметры карты';
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = 'Точность';
            app.RelTolEditFieldLabel = uilabel(app.Panel_6);
            app.RelTolEditFieldLabel.Text = 'RelTol';
            app.RelTolEditField = uieditfield(app.Panel_6, 'numeric');
            app.RelTolEditField.Value = 1e-06;
            app.AbsTolEditFieldLabel = uilabel(app.Panel_6);
            app.AbsTolEditFieldLabel.Text = 'AbsTol';
            app.AbsTolEditField = uieditfield(app.Panel_6, 'numeric');
            app.AbsTolEditField.Value = 1e-07;
            app.MaxStepEditFieldLabel = uilabel(app.Panel_6);
            app.MaxStepEditFieldLabel.Text = 'MaxStep';
            app.MaxStepEditField = uieditfield(app.Panel_6, 'numeric');
            app.MaxStepEditField.Value = 0.1;
            app.EditField_7Label = uilabel(app.Panel_6);
            app.EditField_7Label.Text = 'Время переходного процесса';
            app.EditField_7 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_8Label = uilabel(app.Panel_6);
            app.EditField_8Label.Text = 'Время интегрирования';
            app.EditField_8 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_8.Value = 100;
            app.DropDownLabel = uilabel(app.Panel_4);
            app.DropDownLabel.Text = 'Режим протягивания по НУ';
            app.DropDown = uidropdown(app.Panel_4);
            app.DropDown.Items = {'Без протягивания'};
            app.DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.DropDown.Value = '1';
            app.dPanel = uipanel(app.Panel_4);
            app.dPanel.Title = 'Второй параметр (d)';
            app.EditField_4Label = uilabel(app.dPanel);
            app.EditField_4Label.Text = 'От';
            app.EditField_4 = uieditfield(app.dPanel, 'numeric');
            app.EditField_5Label = uilabel(app.dPanel);
            app.EditField_5Label.Text = 'До';
            app.EditField_5 = uieditfield(app.dPanel, 'numeric');
            app.EditField_5.Value = 3;
            app.EditField_6Label = uilabel(app.dPanel);
            app.EditField_6Label.Text = 'Кол-во узлов';
            app.EditField_6 = uieditfield(app.dPanel, 'numeric');
            app.EditField_6.Value = 10;
            app.Panel_3 = uipanel(app.Panel_4);
            app.Panel_3.Title = 'Фиксированные параметры системы';
            app.GammaEditField_3Label = uilabel(app.Panel_3);
            app.GammaEditField_3Label.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.Panel_3, 'numeric');
            app.GammaEditField.Value = 1.01;
            app.DeltaEditField_3Label = uilabel(app.Panel_3);
            app.DeltaEditField_3Label.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.Panel_3, 'numeric');
            app.SigmaPanel = uipanel(app.Panel_4);
            app.SigmaPanel.Title = 'Первый параметр (Sigma)';
            app.EditFieldLabel = uilabel(app.SigmaPanel);
            app.EditFieldLabel.Text = 'От';
            app.EditField = uieditfield(app.SigmaPanel, 'numeric');
            app.EditField_2Label = uilabel(app.SigmaPanel);
            app.EditField_2Label.Text = 'До';
            app.EditField_2 = uieditfield(app.SigmaPanel, 'numeric');
            app.EditField_2.Value = 3.14159265358979;
            app.EditField_3Label = uilabel(app.SigmaPanel);
            app.EditField_3Label.Text = 'Кол-во узлов';
            app.EditField_3 = uieditfield(app.SigmaPanel, 'numeric');
            app.EditField_3.Value = 10;
            app.EditField_10 = uieditfield(app.Panel_4, 'numeric');
            app.EditField2 = uieditfield(app.Panel_4, 'numeric');
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = 'Начальные условия';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сгенерировать название';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = 'Сохранить карту как';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_9.Value = 'Map';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Создать карту';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogNewMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end