classdef DialogSelectMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        Tree      matlab.ui.container.Tree
    end

    
    properties (Access = protected)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            
            app.Tree.clo();
            list = ls('Map*.mat');
            for i = 1 : size(list, 1)
               uitreenode(app.Tree, 'Text', list(i, :));
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Tree.clo();
            list = ls('Map*');
            for i = 1 : size(list, 1)
               uitreenode(app.Tree, 'Text', list(i, :));
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.Tree.SelectedNodes)
                app.CallingApp.Map = load(app.Tree.SelectedNodes.Text);
                app.CallingApp.Map = app.CallingApp.Map.Map;
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Tree = uitree(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить карту';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Обновить список карт';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Список доступных карт';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogSelectMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end