classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Button_13            matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Panel_6              matlab.ui.container.Panel
        dEditField           matlab.ui.control.NumericEditField
        dEditFieldLabel      matlab.ui.control.Label
        SigmaEditField       matlab.ui.control.NumericEditField
        SigmaEditFieldLabel  matlab.ui.control.Label
        Panel_5              matlab.ui.container.Panel
        Button_9             matlab.ui.control.Button
        CheckBox_5           matlab.ui.control.CheckBox
        CheckBox_4           matlab.ui.control.CheckBox
        CheckBox_3           matlab.ui.control.CheckBox
        CheckBox_2           matlab.ui.control.CheckBox
        CheckBox             matlab.ui.control.CheckBox
        Panel_4              matlab.ui.container.Panel
        Button_12            matlab.ui.control.Button
        EditField_2          matlab.ui.control.EditField
        Label_2              matlab.ui.control.Label
        Button_11            matlab.ui.control.Button
        Button_10            matlab.ui.control.Button
        Panel_3              matlab.ui.container.Panel
        ButtonGroup_2        matlab.ui.container.ButtonGroup
        YEditField           matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        XEditField           matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        Button_8             matlab.ui.control.RadioButton
        Button_7             matlab.ui.control.RadioButton
        EditField            matlab.ui.control.NumericEditField
        Label                matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        Panel                matlab.ui.container.Panel
        Slider               matlab.ui.control.Slider
        Slider_2             matlab.ui.control.Slider
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Map;
        DialogApp;
        WorkStatus;
        SliderPoint;
    end
    
    methods (Access = private)
        
        function InitializeMapPlot(app, ax)
            
            % --- Сетка для карты --- %
            
            FirstParam = linspace(app.Map.MP.FirstParamBorder(1), app.Map.MP.FirstParamBorder(2), app.Map.MP.Width);
            SecondParam = linspace(app.Map.MP.SecondParamBorder(1), app.Map.MP.SecondParamBorder(2), app.Map.MP.Height);
            
            [FirstParamMesh, SecondParamMesh] = meshgrid(FirstParam, SecondParam);
            
            % --- --- %
            cla(ax);
            hold(ax, 'on');
            axis(ax, 'square');
            
            % --- Границы карты --- %
            ax.XLim = app.Map.MP.FirstParamBorder;
            ax.YLim = app.Map.MP.SecondParamBorder;
            
            % --- Подписи осей --- %
%                         AxisLabelsFontSize = 14;
%                         FontSize = 14;
%                         ax.TickLabelInterpreter = 'latex';
%             
%                         ax.XLabel.String = 'Sigma';
%                         ax.XLabel.FontSize = AxisLabelsFontSize;
%             
%                         ax.YLabel.String = 'd';
%                         ax.YLabel.FontSize = AxisLabelsFontSize;
%             
%                         set(ax, 'FontSize', FontSize);
            
            % --- --- %
            ColorMin = 1;
            ColorMax = 256;
            pcolor(ax, [-2, -1;-2, -1], [-2, -2;-1, -1], [ColorMin, ColorMin; ColorMin, ColorMin]);
            pcolor(ax, [-3, -2;-3, -2], [-2, -2;-1, -1], [ColorMax, ColorMax; ColorMax, ColorMax]);
            
            pc = pcolor(ax, FirstParamMesh, SecondParamMesh, app.Map.ValueArray*3 + 50);
            pc.EdgeColor = 'none';
            colormap(ax, colorcube);
            
            % --- Отрисовка вспомогательной точки --- %
            
            app.Slider.Limits = app.Map.MP.FirstParamBorder;
            app.Slider_2.Limits = app.Map.MP.SecondParamBorder;
            
            app.SliderPoint = plot(app.UIAxes, app.Slider.Value, app.Slider_2.Value, 'o', 'MarkerSize', 7, 'MarkerFaceColor', [1 1 1], 'MarkerEdgeColor', [1 0 0], 'LineWidth', 2);
            
        end
        
        function PlotParameters = GetPlotParameters(app)
            SystemParameters = struct('Gamma', [app.Map.SP.Gamma app.Map.SP.Gamma + app.Map.SP.Delta], 'Sigma', app.SigmaEditField.Value, 'd', app.dEditField.Value);
            FigureParameters = struct();
            opts = odeset('RelTol',1e-9,'AbsTol',1e-12, 'MaxStep', 1e-1);
            if app.CheckBox_5.Value == 1
                if app.Button_7.Value == 1
                    IC = ginput;
                else
                    IC = [app.XEditField.Value, app.YEditField.Value];
                end
            else
                IC = [0, 0];
            end
            NumericalParameters = struct('InitialCondition', IC, 'Tspan', app.EditField.Value, 'Options', opts);
            ListOfComponents = struct('AreaOfD', 1, ...
                'Isocline', app.CheckBox.Value, ...
                'Separatrix', app.CheckBox_2.Value, ...
                'MainTrajectories', app.CheckBox_4.Value, ...
                'SupportTrajectory', app.CheckBox_5.Value, ...
                'Equilibria', app.CheckBox_3.Value, ...
                'VectorField', 0);
            PlotParameters = struct('SystemParameters', SystemParameters, 'FigureParameters', FigureParameters, 'NumericalParameters', NumericalParameters, 'ListOfComponents', ListOfComponents);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.DialogApp = DialogSelectMap(app);
            waitfor(app.DialogApp);
            if ~isempty(app.Map)
                app.WorkStatus = "MapLoaded";
                %UpdateInterface(app);
                InitializeMapPlot(app, app.UIAxes);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.SliderPoint.XData = value;
            app.SigmaEditField.Value = value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.SliderPoint.YData = value;
            app.dEditField.Value = value;
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            value = app.SigmaEditField.Value;
            app.SliderPoint.XData = value;
            app.Slider.Value = value;
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            app.SliderPoint.YData = value;
            app.Slider_2.Value = value;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            PlotParameters = GetPlotParameters(app);
            
            if app.CheckBox_5.Value == 1
                Time_Series_Main(app.UIAxes3, PlotParameters);
                Phase_Space_Main(app.UIAxes2, PlotParameters);
            else
                Phase_Space_Main(app.UIAxes2, PlotParameters);
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            %selectedButton = app.ButtonGroup_2.SelectedObject;
            if app.Button_7.Value == 1
                app.XEditField.Enable = 'off';
                app.YEditField.Enable = 'off';
            else
                app.XEditField.Enable = 'on';
                app.YEditField.Enable = 'on';
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            PlotParameters = GetPlotParameters(app);
            fig = figure;
            
            if app.CheckBox_5.Value == 1
                %Параметры фигуры
                fig.Position = [385 455 1050 443];
                
                ax1 = subplot(1, 2, 1);
                axis(ax1, 'square');
                hold(ax1, 'on');
                Phase_Space_Main(ax1, PlotParameters);
                
                ax2 = subplot(1, 2, 2);
                axis(ax2, 'square');
                hold(ax2, 'on');
                Time_Series_Main(ax2, PlotParameters);
            else
                fig.Position = [72 97 577 534];
                ax = gca();
                axis(ax, 'square');
                hold(ax, 'on');
                Phase_Space_Main(ax, PlotParameters);
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.DialogApp = DialogNewMap(app);
            waitfor(app.DialogApp);
            if ~isempty(app.Map)
                app.WorkStatus = "MapLoaded";
                %UpdateInterface(app);
                InitializeMapPlot(app, app.UIAxes);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            IC_Param_Array = load("IC_Param_Array_0.mat");
            IC_Param_Array = IC_Param_Array.IC_Param_Array;
            PlotParameters = GetPlotParameters(app);
            for i = 7:8
                PlotParameters.SystemParameters.Sigma = IC_Param_Array{2, i};
                PlotParameters.SystemParameters.d = IC_Param_Array{3, i};
                PlotParameters.NumericalParameters.InitialCondition = [IC_Param_Array{4, i}, IC_Param_Array{5, i}];
                PlotParameters.NumericalParameters.Tspan = IC_Param_Array{6, i};
                fig = figure;

                if app.CheckBox_5.Value == 1
                    %Параметры фигуры
                    fig.Position = [385 455 1050 443];


                    ax1 = subplot(1, 2, 1);
                    axis(ax1, 'square');
                    hold(ax1, 'on');
                    Phase_Space_Main(ax1, PlotParameters);

                    ax2 = subplot(1, 2, 2);
                    axis(ax2, 'square');
                    hold(ax2, 'on');
                    Time_Series_Main(ax2, PlotParameters);

                    XText1 = -ax1.XLim(2) / 5;
                    XText2 = -ax2.XLim(2) / 5;
                    YText = -0.8;
                    Name = IC_Param_Array{1, i};
                    FontSize = 24;
                    if str2double(Name(length(Name))) == 1
                        text(ax1, XText1, YText,'\fontname{Times} (а)', 'FontSize',FontSize, 'Interpreter', 'tex');
                        text(ax2, XText2, YText,'\fontname{Times} (б)', 'FontSize',FontSize, 'Interpreter', 'tex');
                    elseif str2double(Name(length(Name))) == 2
                            text(ax1, XText1, YText,'\fontname{Times} (в)', 'FontSize',FontSize, 'Interpreter', 'tex');
                            text(ax2, XText2, YText,'\fontname{Times} (г)', 'FontSize',FontSize, 'Interpreter', 'tex');
                    elseif str2double(Name(length(Name))) == 3
                            text(ax1, XText1, YText,'\fontname{Times} (д)', 'FontSize',FontSize, 'Interpreter', 'tex');
                            text(ax2, XText2, YText,'\fontname{Times} (е)', 'FontSize',FontSize, 'Interpreter', 'tex');
                    elseif str2double(Name(length(Name))) == 4
                            text(ax1, XText1, YText,'\fontname{Times} (ж)', 'FontSize',FontSize, 'Interpreter', 'tex');
                            text(ax2, XText2, YText,'\fontname{Times} (з)', 'FontSize',FontSize, 'Interpreter', 'tex');
                    end

                    saveas(fig, Name, 'svg');
                    exportgraphics(fig, [Name, '.pdf']);

                    pause(0.1);
                    close(fig);
                else
                    fig.Position = [72 97 577 534];
                    ax = gca();
                    axis(ax, 'square');
                    hold(ax, 'on');
                    Phase_Space_Main(ax, PlotParameters);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Карта режимов';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'Sigma')
            ylabel(app.UIAxes, 'd')
            app.Slider_2 = uislider(app.Panel);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.MinorTicks = [];
            app.Slider = uislider(app.Panel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Фазовый портрет и временная диаграмма';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Фвзовый портрет системы')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.Panel_2);
            title(app.UIAxes3, 'Временные диаграммы')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Параметры траектории по заданным НУ';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = 'Время интегрирования';
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            app.EditField.Value = 200;
            app.ButtonGroup_2 = uibuttongroup(app.Panel_3);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = 'Способ задания НУ';
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Enable = 'off';
            app.Button_7.Text = 'На фазовом портрете';
            app.Button_8 = uiradiobutton(app.ButtonGroup_2);
            app.Button_8.Text = 'Заданием координат';
            app.Button_8.Value = true;
            app.XEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.YEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Работа с изображениями';
            app.Button_10 = uibutton(app.Panel_4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = 'Предварительный просмотр';
            app.Button_11 = uibutton(app.Panel_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = 'Серия изображений';
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = 'Имя изображения:';
            app.EditField_2 = uieditfield(app.Panel_4, 'text');
            app.Button_12 = uibutton(app.Panel_4, 'push');
            app.Button_12.Enable = 'off';
            app.Button_12.Text = 'Сохранить параметры изображения';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = 'Доп. элементы фазового портрета';
            app.CheckBox = uicheckbox(app.Panel_5);
            app.CheckBox.Text = 'Изоклины';
            app.CheckBox_2 = uicheckbox(app.Panel_5);
            app.CheckBox_2.Text = 'Сепаратрисы';
            app.CheckBox_3 = uicheckbox(app.Panel_5);
            app.CheckBox_3.Text = 'Сост. равновесия';
            app.CheckBox_4 = uicheckbox(app.Panel_5);
            app.CheckBox_4.Text = 'Векторное поле';
            app.CheckBox_5 = uicheckbox(app.Panel_5);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = 'Траектория по заданным НУ';
            app.Button_9 = uibutton(app.Panel_5, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = 'Обновить изображения';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = 'Параметры системы';
            app.SigmaEditFieldLabel = uilabel(app.Panel_6);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Panel_6, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.Panel_6);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Panel_6, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить карту';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = 'Новая карта';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end